classdef APTC_AcousticCalibration < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure                     matlab.ui.Figure
      MethodDropDown               matlab.ui.control.DropDown
      MeasureButton                matlab.ui.control.Button
      MeterButton                  matlab.ui.control.Button
      TransducerDropDown           matlab.ui.control.DropDown
      TransducerDropDownLabel      matlab.ui.control.Label
      Panel                        matlab.ui.container.Panel
      SelectIntegerStepsButton     matlab.ui.control.Button
      UITable                      matlab.ui.control.Table
      ExitButton                   matlab.ui.control.Button
      SaveButton                   matlab.ui.control.Button
      PlayButton                   matlab.ui.control.Button
      AttenEditField               matlab.ui.control.NumericEditField
      AttenuationdBEditFieldLabel  matlab.ui.control.Label
      StopButton                   matlab.ui.control.Button
      StopMeasureButton            matlab.ui.control.Button
   end

   
   properties (Access = private)
      calFolder
      calPath % Description
      attenuation % Description
      isDirty % Description
      haudio % Description
      transducer % Description
      soundMeter % Description
      stopMeasurement % Description
      volumeControl = []
      Efreq
      freq
      calData
      nstep = 8;
      calFig = [];
   end
   
   methods (Access = private)
      
      function OnAudioFinished(app, ~, ~)
         app.UITable.Enable = 'on';
         app.PlayButton.Visible = true;
      end
      
      %--------------------------------------------------------------------
      function SaveData(app)
         cal.FreqHz = app.calData.freq;
         cal.SPL_Vrms = app.calData.savedMax;
         
         ifilt = ~isnan(app.calData.newMax);
         cal.SPL_Vrms(ifilt) = app.calData.newMax(ifilt);
         save(app.calPath, 'cal', '-append');

         app.calData.savedMax = cal.SPL_Vrms;
         app.calData.newMax = NaN*ones(size(app.calData.savedMax));
         app.isDirty = false;

         app.ShowTable();
         app.PlotCalibration();
      end
      
      %--------------------------------------------------------------------
      function LoadCalibration(app)
         app.calPath = fullfile(app.calFolder, [app.transducer '_Calibration.mat']);
         if ~exist(app.calPath, 'file')
            cal.FreqHz = app.freq;
            cal.SPL_Vrms = NaN*ones(size(app.freq));
            save(app.calPath, 'cal');
         end

         load(app.calPath, 'cal');
         Lmax = interp1(cal.FreqHz, cal.SPL_Vrms, app.freq);

         app.calData.freq = app.freq;
         app.calData.savedMax = Lmax;
         app.calData.newMax = NaN*ones(size(Lmax));

         app.isDirty = false;
      end
      
      %--------------------------------------------------------------------
      function ShowTable(app)
         app.UITable.RowName = {};
         d = table(num2str(app.freq, '%d'), num2str(app.calData.savedMax, '%.1f'), cell(size(app.freq)), cell(size(app.freq)), 'VariableNames', {'Freq', 'Old', 'New', 'Measured'});
         app.UITable.Data = d;       
         app.UITable.addStyle(uistyle('HorizontalAlignment', 'right'), 'table', '');
         app.UITable.addStyle(uistyle('BackgroundColor', [0.85 0.85 0.95]), 'row', 1:app.nstep:length(app.freq));
      end

      %--------------------------------------------------------------------
      function PlotCalibration(app)
         if ~isempty(app.calFig)
            figure(app.calFig);
         else
            app.calFig = findobj('Type', 'Figure', 'Name', 'Acoustic calibration');            
            if isempty(app.calFig)
               app.calFig = figure('Name', 'Acoustic calibration', 'NumberTitle', 'off');
            end
         end

         clf(app.calFig);
         hax = axes();
         cmap = get(hax, 'ColorOrder');
         hold(hax, 'on');
         plot(hax, app.calData.freq, app.calData.savedMax, '.', 'Color', cmap(1,:));
         h = plot(hax, app.calData.freq(1:app.nstep:end), app.calData.savedMax(1:app.nstep:end), 'o', 'Color', cmap(1,:));
         set(h, 'MarkerFaceColor', get(h, 'Color'));
         plot(hax, app.calData.freq, app.calData.newMax, '.', 'Color', cmap(2,:));

         set(hax, 'XScale', 'log');
         xlabel(hax, 'Frequency (Hz)');
         ylabel(hax, 'Max dB SPL');

         set(hax, 'XLim', [min(app.Efreq)/2^0.25 max(app.Efreq)*2^0.25])
         set(hax, 'XTick', app.Efreq);
         set(hax, 'XMinorTick', 'off');

         grid(hax, 'on');
         set(hax, 'XMinorGrid', 'off');
      end

      %--------------------------------------------------------------------
      function AcquireData(app)
         ifreq = app.UITable.Selection;
         if isempty(ifreq)
            return;
         end

         app.stopMeasurement = false;
         app.PlayButton.Enable = false;
         app.StopMeasureButton.Enable = true;
         app.MeasureButton.Visible = false;

         Fs = 50000;
         T = 5;
         npts = round(Fs * T);
         t = (0:npts-1) / Fs;

         gate = app.CreateGate(Fs, npts, 20);

         try
            app.SetPCVolume();

             di = audiodevinfo();
             idev = find(contains({di.output.Name}, 'Speakers (MOTU Pro Audio)'));
             if isempty(idev)
               % see note under Play button callback
               idev = find(contains({di.output.Name}, 'USB Sound Device'));
               if isempty(idev)
                  error('Cannot find MOTU speaker channels');
               end
             end

            for kf = 1:length(ifreq)

               app.UITable.Selection = ifreq(kf);
               f = str2double(app.UITable.Data.Freq(ifreq(kf),:));

               y = gate .* sin(2*pi*t*f);
               y = sqrt(2) * y * 10 ^ (app.attenuation / 20);

               h = audioplayer(y, Fs, 24, di.output(idev).ID); %#ok<TNMLP>
               play(h);

               value = app.soundMeter.AcquireValue(f);
               app.UITable.Data.Measured(ifreq(kf)) = {num2str(value, '%.1f')};
               app.UITable.Data.New(ifreq(kf)) = {num2str(value - app.attenuation, '%.1f')};
               app.calData.newMax(ifreq(kf)) = value - app.attenuation;
               app.PlotCalibration();
               app.isDirty = true;
               
               while isplaying(h)
                  pause(0.2);
               end

               if app.stopMeasurement
                  break;
               end
            end
         catch ex
            errordlg(ex.message);
         end

         app.PlayButton.Enable = true;
         app.MeasureButton.Visible = true;
         app.UITable.Enable = 'on';
      end

     %--------------------------------------------------------------------
     function G = CreateGate(~, Fs, numPoints, ramplen)
         sinePeriod_s = 2 * ramplen / 1000;
         npts = sinePeriod_s * Fs;
         sineFrequency = 1 / sinePeriod_s;

         sineBuffer = -cos(2*pi*sineFrequency*(0:npts-1)/Fs);
         sineBuffer = (sineBuffer + 1)/2;

         %  Split the sinusoid in half and fill center with ones
         numOnes = numPoints - length(sineBuffer);
         G = [sineBuffer(1:round(npts/2)) ones(1,numOnes) sineBuffer((round(npts/2)+1):end)];
      end

      %--------------------------------------------------------------------
      function SetPCVolume(app)
         if ~isempty(app.volumeControl)
            app.volumeControl.SetVolume(0.25);
         end         
      end
      
      %--------------------------------------------------------------------
      function InitializeVolumeControl(app)
         try
            NET.addAssembly(fullfile(fileparts(which(mfilename)), 'KLib.VolumeManager.dll'));
            app.volumeControl = KLib.VolumeManager;
            app.volumeControl.SetVolume(0.25);
         catch ex
            errordlg(['Failed to initialize volume control: ' newline ex.message newline 'Make sure PC volume is maxed out.'], 'UWCAMP');
            app.volumeControl = [];
         end
      end
   end
   

   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app, freq, calFolder, transducer)
         app.Efreq = freq(:);
         app.calFolder = calFolder;
         app.transducer = transducer;

         % Subsample electrode frequency spacing
         ifreq = (0:app.nstep-1) / app.nstep;
         df = diff(app.Efreq);
         app.freq = app.Efreq(1:end-1)' + (ifreq' * df');
         app.freq = round([app.freq(:); app.Efreq(end)]);

         % Restore attenuation
         app.attenuation = getsharedpref('APTC_AcousticCalibration', 'Attenuation', -20);
         app.AttenEditField.Value = app.attenuation;

         % Restore figure position
         pos = app.UIFigure.Position;
         monitorPos = get(0, 'MonitorPositions');
         iprimary = find(monitorPos(:, 1) == 1);
         x = 0.5*(monitorPos(iprimary, 3) - pos(3));
         y = 0.5*(monitorPos(iprimary, 4) - pos(4));
%          rmpref('APTC_AcousticCalibration', 'Position');
         origin = getsharedpref('APTC_AcousticCalibration', 'Position', [x y pos(3:4)]);
         if ~isempty(origin)
            pos(1:2) = origin(1:2);
            app.UIFigure.Position = pos;
         end

         % Fill transducer drop down
         flist = dir(fullfile(app.calFolder, '*_Calibration.mat'));
         t = {};
         for k = 1:length(flist)
            parts = strsplit(flist(k).name, '_Calibration');
            t{end+1} = parts{1}; %#ok<AGROW> 
         end
         app.TransducerDropDown.Items = t;
         app.TransducerDropDown.Value = app.transducer;

         app.LoadCalibration();
         app.ShowTable();
         app.PlotCalibration();

         app.InitializeVolumeControl();
      end

      % Close request function: UIFigure
      function UIFigureCloseRequest(app, event)
         if app.isDirty
            response = questdlg('You have unsaved changes to the calibration.', '', 'Save', 'Don''t save', 'Cancel', 'Save');
            if isequal(response, 'Cancel')
               return;
            elseif isequal(response, 'Save')
               app.SaveData();
            end
         end
         
        if ~isempty(app.soundMeter)
           try
              app.soundMeter.Quit();
           catch
           end
        end

         pos = app.UIFigure.Position;
         setsharedpref('APTC_AcousticCalibration', 'Position', pos(1:2));
         setsharedpref('APTC_AcousticCalibration', 'Attenuation', app.attenuation);

         uiresume(app.UIFigure);

         delete(app)
      end

      % Button pushed function: ExitButton
      function ExitButtonPushed(app, event)
         close(app.UIFigure);
      end

      % Value changed function: AttenEditField
      function AttenEditFieldValueChanged(app, event)
         app.attenuation = app.AttenEditField.Value;         
      end

      % Button pushed function: PlayButton
      function PlayButtonPushed(app, event)
         try
            ifreq = app.UITable.Selection;
            if isempty(ifreq) || length(ifreq) > 1
               return;
            end
            app.PlayButton.Enable = 'off';

            f = str2double(app.UITable.Data.Freq(ifreq,:));

            Fs = 50000;
            T = 30;
            npts = round(Fs * T);
            t = (0:npts-1) / Fs;
            y = app.CreateGate(Fs, npts, 20) .* sin(2*pi*t*f);
            y = sqrt(2) * y * 10 ^ (app.attenuation / 20);

            app.UITable.Enable = 'off';

            di = audiodevinfo();
            idev = find(contains([di.output.Name], 'Speakers (MOTU Pro Audio)'));
            if isempty(idev)
               % Allow USB sound card for dev purposes. If it happens to
               % pass this test on deployment system, it's not the end of
               % the world; there will simply be no signal and the user
               % will have to troubleshoot anyway. If the user is
               % calibrating, they should be savvy enough to deal with it.
               idev = find(contains({di.output.Name}, 'USB Sound Device'));
               if isempty(idev)
                  error('Cannot find MOTU speaker channels');
               end
            end

            app.SetPCVolume()
            app.haudio = audioplayer([y y], Fs, 24, di.output(idev).ID);
            app.haudio.StopFcn = @app.OnAudioFinished;

            app.PlayButton.Visible = false;
            play(app.haudio);
         catch ex
            errordlg(ex.message);
            app.UITable.Enable = 'on';
            app.PlayButton.Visible = true;
         end

         app.PlayButton.Enable = 'on';
      end

      % Button pushed function: StopButton
      function StopButtonPushed(app, event)
         stop(app.haudio);
      end

      % Cell edit callback: UITable
      function UITableCellEdit(app, event)
         indices = event.Indices;
         newData = event.NewData;
         newSPL = newData - app.attenuation;
         app.UITable.Data.New(indices(1)) = {newSPL};
         app.isDirty = true;
      end

      % Button pushed function: SaveButton
      function SaveButtonPushed(app, event)
         app.SaveData();
      end

      % Value changed function: TransducerDropDown
      function TransducerDropDownValueChanged(app, event)
         if app.isDirty
            response = questdlg('You have unsaved changes to the calibration.', '', 'Save', 'Don''t save', 'Cancel', 'Save');
            if isequal(response, 'Save')
               app.SaveData();
            elseif isequal(response, 'Cancel')
               app.TransducerDropDown.Value = app.transducer;
               return;
            end
         end
         app.transducer = app.TransducerDropDown.Value;
         app.LoadCalibration();
         app.ShowTable();
         app.PlotCalibration();
      end

      % Button pushed function: MeterButton
      function MeterButtonPushed(app, event)
         app.soundMeter = Sound_Meter();
      end

      % Button pushed function: MeasureButton
      function MeasureButtonPushed(app, event)
         app.AcquireData();
      end

      % Button pushed function: StopMeasureButton
      function StopMeasureButtonPushed(app, event)
         app.StopMeasureButton.Enable = false;
         app.stopMeasurement = true;
      end

      % Button pushed function: SelectIntegerStepsButton
      function SelectIntegerStepsButtonPushed(app, event)
         app.UITable.Selection = 1 : app.nstep : length(app.UITable.Data.Freq);
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.Name = 'Acoustic Calibration';
         app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
         app.StopMeasureButton = uibutton(app.UIFigure, 'push');
         app.StopMeasureButton.ButtonPushedFcn = createCallbackFcn(app, @StopMeasureButtonPushed, true);
         app.StopMeasureButton.Text = 'Stop';
         app.StopButton = uibutton(app.UIFigure, 'push');
         app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
         app.StopButton.Text = 'Stop';
         app.AttenuationdBEditFieldLabel = uilabel(app.UIFigure);
         app.AttenuationdBEditFieldLabel.Text = 'Attenuation (dB)';
         app.AttenEditField = uieditfield(app.UIFigure, 'numeric');
         app.AttenEditField.Limits = [-Inf -3];
         app.AttenEditField.ValueChangedFcn = createCallbackFcn(app, @AttenEditFieldValueChanged, true);
         app.AttenEditField.Value = -3;
         app.PlayButton = uibutton(app.UIFigure, 'push');
         app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
         app.PlayButton.Text = 'Play';
         app.SaveButton = uibutton(app.UIFigure, 'push');
         app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
         app.SaveButton.Text = 'Save';
         app.ExitButton = uibutton(app.UIFigure, 'push');
         app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
         app.ExitButton.Text = 'Exit';
         app.Panel = uipanel(app.UIFigure);
         app.UITable = uitable(app.Panel);
         app.UITable.ColumnName = {'Freq'; 'Old SPL'; 'New SPL'; 'Measured'};
         app.UITable.RowName = {''; ''};
         app.UITable.SelectionType = 'row';
         app.UITable.ColumnEditable = [false false false true];
         app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
         app.SelectIntegerStepsButton = uibutton(app.Panel, 'push');
         app.SelectIntegerStepsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectIntegerStepsButtonPushed, true);
         app.SelectIntegerStepsButton.Text = 'Select integer steps only';
         app.TransducerDropDownLabel = uilabel(app.UIFigure);
         app.TransducerDropDownLabel.Text = 'Transducer';
         app.TransducerDropDown = uidropdown(app.UIFigure);
         app.TransducerDropDown.Items = {'Default'};
         app.TransducerDropDown.Editable = 'on';
         app.TransducerDropDown.ValueChangedFcn = createCallbackFcn(app, @TransducerDropDownValueChanged, true);
         app.TransducerDropDown.Value = 'Default';
         app.MeterButton = uibutton(app.UIFigure, 'push');
         app.MeterButton.ButtonPushedFcn = createCallbackFcn(app, @MeterButtonPushed, true);
         app.MeterButton.Text = 'Meter';
         app.MeasureButton = uibutton(app.UIFigure, 'push');
         app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureButtonPushed, true);
         app.MeasureButton.Text = {'Measure'; ''};
         app.MethodDropDown = uidropdown(app.UIFigure);
         app.MethodDropDown.Items = {'Chirp', 'Tone sweep'};
         app.MethodDropDown.Editable = 'on';
         app.MethodDropDown.Visible = 'off';
         app.MethodDropDown.Value = 'Tone sweep';
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = APTC_AcousticCalibration(varargin)

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.UIFigure)

         % Execute the startup function
         runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end