classdef APTC_ResponseWindow < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure        matlab.ui.Figure
      GridLayout      matlab.ui.container.GridLayout
      Button1         matlab.ui.control.Button
      PromptTextArea  matlab.ui.control.TextArea
      Button2         matlab.ui.control.Button
      Button3         matlab.ui.control.Button
   end

   
   properties (Access = private)
      state % Description
      buttons = [] % Description
      Tstart_ms = NaN;
      Tdur_ms = NaN;
      correctInterval = 0;
      responseTimeStart = NaN;
      intertrialPause_s = 0.5;
      volumeControl = [];
      presentationMode;
      hkeepalive;
      animated
      buttonFolder = 'C:\EPL\Resources\GIFs';
      buttonList = [];
      oncolor = [1 0 0];
      offcolor = [0.46 0.78 1];
   end
   
   properties (SetAccess = private, GetAccess = public)
      Response = [];
   end
   
   properties (Access = public)
      DebugMode = 'off';
   end
   
   properties (Access = public)
      VisualCueDelay = 0;
   end
   
   methods (Access = public)
      
      function Initialize(app, numButtons, Tstart_ms, Tdur_ms, mode, method)
         app.presentationMode = mode;
         app.Tstart_ms = Tstart_ms;
         app.Tdur_ms = Tdur_ms;

         app.animated = strcmpi(method, 'pediatric');

         if ~strcmpi(mode, 'normal hearing')
            app.StartKeepAliveSound();
         end

         app.LayoutWindow(method, numButtons);
      end

      %--------------------------------------------------------------------
      function LayoutWindow(app, method, numButtons)

         if strcmpi(method, 'pediatric')
            pos = get(app.UIFigure, 'Position');
            xc = pos(1) + pos(3) / 2;
            yc = pos(2) + pos(4) / 2;
            
            w = 705;
            h = 455;

            if numButtons == 2
               w = round(2/3 * w);
            end

            pos = [round(xc - w/2) round(yc - h/2) w h];
            set(app.UIFigure, 'Position', pos);
            
            app.oncolor = [1 1 .4];
            app.offcolor = [.90 .90 .82];
            for k = 1:length(app.buttons)
               set(app.buttons(k), 'BackgroundColor', app.offcolor);
            end
            set(app.GridLayout, 'BackgroundColor', [0.87 0.92 0.98]);
   
            app.buttonList = dir(fullfile(app.buttonFolder, '*.gif'));
         end

         if numButtons == 2
            app.GridLayout.ColumnWidth{end} = 0;
         end
      end

      %--------------------------------------------------------------------
      function PromptToStart(app, prompt)
         app.PromptTextArea.Value = [prompt newline newline 'Press any key to start.'];
         app.state = 'WaitingToContinue';
      end

      %--------------------------------------------------------------------
      function ShowMessage(app, message, duration_s)
         app.PromptTextArea.Value = message;
         pause(duration_s);
         app.PromptTextArea.Value = '';
      end

      %--------------------------------------------------------------------
      function ClearAnimations(app)
         for k = 1:length(app.buttons)
            set(app.buttons(k), 'Text', '');
            set(app.buttons(k), 'Icon', '');
         end
      end

      %--------------------------------------------------------------------
      function PlayTrial(app, Fs, signal, correctInterval)
         if isequal(app.DebugMode, 'fast')
            app.PromptTextArea.Value = 'debugging mode';
            app.state = 'Idle';
            drawnow;
            return;
         end

         if app.animated
            index = randi(length(app.buttonList));
            gifPath = fullfile(app.buttonFolder, app.buttonList(index).name);
            app.ClearAnimations();
            drawnow;
         end

         app.Response = [];
         app.state = 'Playing';
         app.correctInterval = correctInterval;
%          fprintf('correct = %d\n', correctInterval);

         app.Button1.Enable = true;
         app.Button2.Enable = true;

         pause(app.intertrialPause_s);

         nvisdelay = round(Fs * app.VisualCueDelay/1000);
         if nvisdelay >= 0
            signal = [signal; zeros(nvisdelay, 2)];
         else
            signal = [zeros(abs(nvisdelay), 2); signal];
            nvisdelay = 0;
         end

         di = audiodevinfo();
         if strcmpi(app.presentationMode, 'normal hearing')
            idev = find(contains({di.output.Name}, 'Speakers (MOTU Pro Audio)'));
            if isempty(idev)
               error('Cannot find MOTU speaker channels');
            end
         else
            idev = 1;
         end

         app.SetPCVolume();
         h = audioplayer(signal, Fs, 24, di.output(idev).ID); 
         h.StopFcn = @app.OnAudioFinished;
         
         if isempty(app.Tstart_ms)
            playblocking(h);
         
         else
            if ~strcmpi(app.presentationMode, 'normal hearing')
               pause(app.hkeepalive);
            end

            play(h);

            istart = round(Fs * app.Tstart_ms/1000) + nvisdelay;
            istop = istart + round(Fs * app.Tdur_ms/1000);

            for kb = 1:length(istart)
               while h.CurrentSample < istart(kb), end
               set(app.buttons(kb), 'BackgroundColor', app.oncolor);
               if app.animated
                  set(app.buttons(kb), 'Icon', gifPath);
               end
               set(app.buttons(kb), 'BackgroundColor', [1 0 0]);
               drawnow;

               while h.CurrentSample < istop(kb) && h.CurrentSample > 1, end
               set(app.buttons(kb), 'BackgroundColor', app.offcolor);
               set(app.buttons(kb), 'Icon', '');
               drawnow;
            end

            while h.CurrentSample > 1, end

            if app.animated
               for k = 1:length(app.buttons)
                  set(app.buttons(k), 'Icon', gifPath);
               end
            end

            if ~strcmpi(app.presentationMode, 'normal hearing')
               play(app.hkeepalive);
            end

         end
      end

      %--------------------------------------------------------------------
      function Finish(app, prompt)
         app.PromptTextArea.Value = prompt;
         app.state = 'Idle';
         pause(1);
         app.UIFigureCloseRequest([]);
      end
   end
   
   methods (Access = private)     
      function OnAudioFinished(app, ~, ~)
         if ~isequal(app.DebugMode, 'off')
            app.PromptTextArea.Value = 'debugging mode';
            app.state = 'Idle';
            uiresume(app.UIFigure);
            return;
         end

         app.responseTimeStart = tic;
         app.PromptTextArea.Value = 'Which interval?';
         app.state = 'WaitingForResponse';
      end      

      %--------------------------------------------------------------------
      function SetPCVolume(app)
         if ~isempty(app.volumeControl)
            volume = 1.0;
            if strcmpi(app.presentationMode, 'normal hearing')
               volume = 0.25;
            end

            app.volumeControl.SetVolume(volume);
         end         
      end
      
      %---------------------------------------------------------------
      function StartKeepAliveSound(app)
         duration = 600;                % duration in seconds
         amplitude = 0;                 % amplitude
         freq = 500;                    % frequency in Hertz

         Fs = 48000;                    % sampling rate
         T = 1/Fs;                      % sampling period
         t = 0:T:duration;              % time vector

         %create the signal
         signal = amplitude * cos(2 * pi * freq * t);

         app.hkeepalive = audioplayer(signal, Fs);
         app.hkeepalive.StopFcn = @app.OnAudioFinished;
         play(app.hkeepalive);
         disp('Bluetooth ''keep alive'' sound started.');
      end

      %---------------------------------------------------------------
      function StopKeepAliveSound(app)
         try
            %             stop(app.hkeepalive);
            delete(app.hkeepalive);
            disp('Bluetooth ''keep alive'' sound stopped.');
         catch
         end
      end

      %--------------------------------------------------------------------
      function InitializeVolumeControl(app)
         try
            NET.addAssembly(fullfile(fileparts(which(mfilename)), 'KLib.VolumeManager.dll'));
            app.volumeControl = KLib.VolumeManager;
%             app.volumeControl.SetVolume(0.25);
         catch ex
            errordlg(['Failed to initialize volume control: ' newline ex.message newline 'Make sure PC volume is maxed out.'], 'UWCAMP');
            app.volumeControl = [];
         end
      end
   end


   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app)
         % Restore figure position
         origin = getpref('APTC_ResponseWindow', 'Position', []);
         if ~isempty(origin)
            pos = app.UIFigure.Position;
            pos(1:2) = origin;
            pos(1) = max(pos(1), 1);
            app.UIFigure.Position = pos;
         end
         
         nbuttons = 3;
         app.buttons = zeros(nbuttons, 1);
         for kb = 1:nbuttons
            h = app.(sprintf('Button%d', kb));
            h.UserData = kb;
            app.buttons(kb) = h;
         end

         app.state = 'Idle';

         app.InitializeVolumeControl();
      end

      % Close request function: UIFigure
      function UIFigureCloseRequest(app, event)
         if ~isequal(app.state, 'Playing')
            if ~strcmpi(app.presentationMode, 'normal hearing')
               app.StopKeepAliveSound();
            end
            uiresume(app.UIFigure);
            pos = app.UIFigure.Position;
            setpref('APTC_ResponseWindow', 'Position', pos(1:2));
            delete(app)
         end
      end

      % Key release function: UIFigure
      function UIFigureKeyRelease(app, event)
          if isequal(app.state, 'WaitingToContinue')
            app.PromptTextArea.Value = '';
            app.state = 'Idle';
            uiresume(app.UIFigure);
         end
      end

      % Button pushed function: Button1, Button2, Button3
      function ButtonPushed(app, event)
         if isequal(app.state, 'WaitingForResponse')
            app.Response.correctResponse = app.correctInterval;
            app.Response.actualResponse = event.Source.UserData;
            app.Response.reactionTime = toc(app.responseTimeStart);

            if app.Response.actualResponse == app.Response.correctResponse
               app.PromptTextArea.Value = '--- CORRECT ---';
            else
               app.PromptTextArea.Value = '--- WRONG ---';
            end
            
            app.state = 'Idle';
            uiresume(app.UIFigure);
         end         
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
         app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
         app.GridLayout = uigridlayout(app.UIFigure);
         app.Button3 = uibutton(app.GridLayout, 'push');
         app.Button3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
         app.Button3.Text = '3';
         app.Button2 = uibutton(app.GridLayout, 'push');
         app.Button2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
         app.Button2.Text = {'2'; ''};
         app.PromptTextArea = uitextarea(app.GridLayout);
         app.PromptTextArea.Editable = 'off';
         app.PromptTextArea.Value = {'Prompt'};
         app.Button1 = uibutton(app.GridLayout, 'push');
         app.Button1.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
         app.Button1.Text = '1';
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = APTC_ResponseWindow

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.UIFigure)

         % Execute the startup function
         runStartupFcn(app, @startupFcn)

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end