classdef ProgressBar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        LoadingPanel      matlab.ui.container.Panel
        TurnaroundButton  matlab.ui.control.Button
        CorrectButton     matlab.ui.control.Button
        Image             matlab.ui.control.Image
        ImageShadow       matlab.ui.control.Image
    end

    
    properties (Access = public)
        NumOfTurnArounds = 3 % The large amounts of progress
        ProgressPerCorrect = 0.4
        LeftOver;

        ImagePxlsPerTurnaround = 100;
        ImageStart = 0;
        ImagePercentHeight = 112/320;
        ImageHeightFromTop = 320-112;

        GaugeWidth = 100;
        PixelStart = 55;

        AnimationFrame = 1;
        Animated = 1;

        ImmediateCorrect = 0;
        Turnarounds = 0;

        Numerator;
        Ticks;

        CustomTickMarkers = [];
        CustomTickPercentHeight;
        CustomTickHeightFromTop;
        CustomTickWidth = 120;

        AnimationFrame1_filename = 'rabbit1.png';
        AnimationFrame2_filename = 'rabbit2.png';

        CorrectTurnaroundLabels = {"Let's go!",'Nice!','Great work!!','WOOHOO!','Wow!','Amazing!','Oh wow!'};
    end
    
    methods (Access = private)
        
        function AnimationFrame = UpdateImageAnimation(app,AnimationFrame)

            if AnimationFrame == 1
                app.Image.ImageSource = app.AnimationFrame2_filename;
                app.ImageShadow.ImageSource = 'shadow2.png';
                AnimationFrame = 2;
            else
                app.Image.ImageSource = app.AnimationFrame1_filename;
                app.ImageShadow.ImageSource = 'shadow1.png';
                AnimationFrame = 1;
            end

        end

        function UpdateWindowParameters(app)
            TotalLength = app.UIFigure.Position(3);
            app.ImagePxlsPerTurnaround = TotalLength/(app.NumOfTurnArounds+2);
            app.CustomTickMarkers = app.CustomTickMarkers;

            for a = 1:length(app.Ticks)
                customMarker = app.CustomTickMarkers(a);
                customMarker.Text = app.CorrectTurnaroundLabels{a};
                customMarker.Position = [(app.ImagePxlsPerTurnaround*a)-(app.CustomTickWidth/2) app.UIFigure.Position(4)-app.CustomTickHeightFromTop app.CustomTickWidth 22];
                app.CustomTickMarkers(a) = customMarker;
            end

        end

        function UpdateImagePosition(app)
            if ~isempty(app.CustomTickMarkers)
                app.ImageStart = app.CustomTickMarkers(1).Position(1) - app.Image.Position(3)/2 + (app.CustomTickWidth/2);
            else
                app.ImageStart = app.ImagePxlsPerTurnaround - app.Image.Position(3)/2;
            end

            app.Image.Position(2) = app.UIFigure.Position(4)-app.ImageHeightFromTop;


            if app.Animated == 0
                % Set x position
                app.Image.Position(1) = GenerateImagePosition(app);
                UpdateShadowPosition(app)
            else
                currentPosition = app.Image.Position(1);
                finalPosition = GenerateImagePosition(app);

                if currentPosition ~= finalPosition
                    div = round(finalPosition - currentPosition)/4;
    
                    subPositions = [div div*2 div*3]+currentPosition;
                    app.AnimationFrame = UpdateImageAnimation(app,app.AnimationFrame);
                    
                    for a=1:3
                        
                        app.Image.Position(1) = subPositions(a);
                        UpdateShadowPosition(app);
                        app.AnimationFrame = UpdateImageAnimation(app,app.AnimationFrame);
                        drawnow;
                        pause(0.1);
                    end
                    app.Image.Position(1) = finalPosition;
                    UpdateShadowPosition(app);
                    app.AnimationFrame = UpdateImageAnimation(app,app.AnimationFrame);

                    if app.AnimationFrame == 2
                        pause(0.1);
                        app.AnimationFrame = UpdateImageAnimation(app,app.AnimationFrame);
                    end
                elseif app.AnimationFrame == 2
                    app.AnimationFrame = UpdateImageAnimation(app,app.AnimationFrame);
                end
            end


        end
        
        function position_x = GenerateImagePosition(app)
            if app.Turnarounds < app.NumOfTurnArounds
                position_x = app.ImageStart + ...
                    app.ImagePxlsPerTurnaround*app.Turnarounds +...
                    app.ImagePxlsPerTurnaround*(0.8-(0.8*exp(-1*app.ProgressPerCorrect*app.ImmediateCorrect)));
            else
                position_x = app.UIFigure.Position(3) + app.Image.Position(3);
            end
        end
        
        function UpdateShadowPosition(app)
            app.ImageShadow.Position = app.Image.Position;
            app.ImageShadow.Position(1) = app.Image.Position(1)-(app.Image.Position(3)*0.02);
            app.ImageShadow.Position(2) = app.Image.Position(2)-(app.Image.Position(4)*0.02);
        end
        
        function UpdateLabels(app)
            for a = 0:app.Turnarounds
                app.CustomTickMarkers(a+1).Visible = 'on';
            end
        end
    end
    
    methods (Access = public)
        
        function CorrectTurnaround(app)
            if (app.Turnarounds < app.NumOfTurnArounds)
                app.Turnarounds = app.Turnarounds + 1;
            end

            app.ImmediateCorrect = 0;
            
            UpdateWindowParameters(app);
            app.AnimationFrame = UpdateImageAnimation(app,app.AnimationFrame);
            UpdateImagePosition(app);
            UpdateLabels(app);
        end
        
        function CorrectTrial(app)
            app.ImmediateCorrect = app.ImmediateCorrect + 1;

            UpdateWindowParameters(app);
            app.AnimationFrame = UpdateImageAnimation(app,app.AnimationFrame);
            UpdateImagePosition(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
           
            app.Numerator = floor(100/app.NumOfTurnArounds);
            app.Ticks = round(linspace(0,100,(app.NumOfTurnArounds+1)));

            % Initialize Window Parameters
            TotalLength = app.UIFigure.Position(3);
            app.ImagePxlsPerTurnaround = TotalLength/(app.NumOfTurnArounds+2);
            % Create checkboxes
            for a = 1:length(app.Ticks)
                 newCheck = uilabel(app.UIFigure);
                 newCheck.Text = 'Start!';
                 newCheck.Visible = 'off';
                 newCheck.HorizontalAlignment = 'center';
                 newCheck.FontSize = 14;
                 newCheck.FontColor = 'white';
                 newCheck.FontWeight = 'bold';
                 app.CustomTickMarkers = [app.CustomTickMarkers newCheck];
            end

            app.CustomTickPercentHeight = 14/app.UIFigure.Position(4);
            app.CustomTickHeightFromTop = app.UIFigure.Position(4) - 14;
            app.ImagePercentHeight = app.Image.Position(2)/app.UIFigure.Position(4);
            app.ImageHeightFromTop = app.UIFigure.Position(4) - app.Image.Position(2);

            UpdateWindowParameters(app);
            app.UIFigure.WindowState = 'maximized';
            
            %Create shadows
            [img, map, alpha] = imread(app.AnimationFrame1_filename);
            img_black = zeros(size(img), 'like', img);  % Create an all-black image of the same size
            img_black(:, :, :) = 0;  % Set to black (RGB = 0)
            img_black = imgaussfilt(img_black, 2);
            imwrite(img_black, 'shadow1.png', 'Alpha', alpha);

            [img, map, alpha] = imread(app.AnimationFrame2_filename);
            img_black = zeros(size(img), 'like', img);  % Create an all-black image of the same size
            img_black(:, :, :) = 0;  % Set to black (RGB = 0)
            img_black = imgaussfilt(img_black, 2);
            imwrite(img_black, 'shadow2.png', 'Alpha', alpha);

            app.AnimationFrame = UpdateImageAnimation(app,app.AnimationFrame);
            UpdateLabels(app);
            UpdateImagePosition(app);
            UpdateShadowPosition(app);

            app.LoadingPanel.Visible = 'off';
        end

        % Button pushed function: TurnaroundButton
        function TurnaroundPress(app, event)
            CorrectTurnaround(app);
        end

        % Button pushed function: CorrectButton
        function CorrectPress(app, event)
            CorrectTrial(app);
        end

        % Size changed function: UIFigure
        function SizeChangedFcn(app, event)
            position = app.UIFigure.Position;
            
            UpdateWindowParameters(app);
            UpdateImagePosition(app);
            UpdateShadowPosition(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @SizeChangedFcn, true);
            app.ImageShadow = uiimage(app.UIFigure);
            app.ImageShadow.ImageSource = 'rabbit1.png';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'rabbit1.png';
            app.CorrectButton = uibutton(app.UIFigure, 'push');
            app.CorrectButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectPress, true);
            app.CorrectButton.Visible = 'off';
            app.CorrectButton.Text = 'Correct';
            app.TurnaroundButton = uibutton(app.UIFigure, 'push');
            app.TurnaroundButton.ButtonPushedFcn = createCallbackFcn(app, @TurnaroundPress, true);
            app.TurnaroundButton.Visible = 'off';
            app.TurnaroundButton.Text = 'Turnaround';
            app.LoadingPanel = uipanel(app.UIFigure);
            app.LoadingPanel.Title = 'Loading';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProgressBar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end