classdef Sound_Meter < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure                        matlab.ui.Figure
      MicGainEditField                matlab.ui.control.NumericEditField
      MicgaindBLabel                  matlab.ui.control.Label
      dBSPLLabel_2                    matlab.ui.control.Label
      VrmsField                       matlab.ui.control.NumericEditField
      dBSPLLabel                      matlab.ui.control.Label
      SPLField                        matlab.ui.control.NumericEditField
      StopButton                      matlab.ui.control.Button
      MicSensitivityEditField         matlab.ui.control.NumericEditField
      MicsensitivitydBVSPLLabel       matlab.ui.control.Label
      MeasureButton                   matlab.ui.control.Button
      PistonphoneEditField            matlab.ui.control.NumericEditField
      PistonphonedBSPLEditFieldLabel  matlab.ui.control.Label
      ResolutionHzEditField           matlab.ui.control.NumericEditField
      ResolutionHzEditFieldLabel      matlab.ui.control.Label
      RunButton                       matlab.ui.control.Button
      TimeAxes                        matlab.ui.control.UIAxes
      FreqAxes                        matlab.ui.control.UIAxes
   end

   
   properties (Access = private)
      running = false;
      daq_handle = [];
      time = [];
      freq = [];
      correctionFactor = 1;
      acquiredValue = NaN;
      numAcquired = 0;
      acquire = false;
      acqFreq = [];
   end
   
   methods (Access = private)
      
      function StartAcquisition(app)
         d = uiprogressdlg(app.UIFigure, 'Message', 'Starting...', 'Indeterminate', 'on');

         try
            app.UpdateCorrectionFactor();

            Fs = 50000;
            resol = app.ResolutionHzEditField.Value;
            
            npts = round(Fs / resol);
            app.time = (0:npts-1) * 1000 / Fs;
            app.freq = (0:npts-1) * resol;
            app.freq = app.freq(1:end/2);

            dlist = daqlist('ni');
            deviceName = dlist.DeviceID{end};

            app.daq_handle = daq('ni');
            app.daq_handle.Rate = Fs;
            addinput(app.daq_handle, deviceName, "ai0", "Voltage");

            app.daq_handle.ScansAvailableFcn = @(src,event) app.ProcessData();
            app.daq_handle.ScansAvailableFcnCount = npts;

            start(app.daq_handle, 'continuous');

            delete(d);
            app.StopButton.Enable = true;
            app.running = true;

         catch ex
            delete(d);
            errordlg(ex.message);
            app.RunButton.Enable = true;
         end

      end
      
      %--------------------------------------------------------------------
      function ProcessData(app)
         data = read(app.daq_handle, 'all');

         y = data.Variables;

         if length(y) ~= length(app.time)
%             disp(length(y));
            return;
         end

         y = y - mean(y);

         plot(app.TimeAxes, app.time, y, 'Color', [0 1 0]);

         s = 20*log10(sqrt(2) * abs(fft(y)) / length(y)) + app.correctionFactor;
         s(1) = NaN;
         plot(app.FreqAxes, app.freq, s(1:end/2), 'Color', [0 1 0]);
         set(app.FreqAxes, 'XLim', [0 10000]);

         if app.acquire
            ifreq = round(app.acqFreq / app.ResolutionHzEditField.Value) + 1;
            spl = s(ifreq);
         else
            vrms = 20*log10(sqrt(mean(y.^2)));
            spl =  vrms + app.correctionFactor;
            app.VrmsField.Value = vrms;
         end

         app.SPLField.Value = spl;

         if app.acquire
            app.numAcquired = app.numAcquired + 1;
            fprintf('%d: %f\n', app.numAcquired, spl);
            if app.numAcquired == 3
               app.acquiredValue = spl;
               app.acquire = false;
               xline(app.FreqAxes, app.acqFreq, 'r');
               yline(app.FreqAxes, spl, 'r');
            end
         end         
      end

      %--------------------------------------------------------------------
      function UpdateCorrectionFactor(app)
         app.correctionFactor = -(app.MicSensitivityEditField.Value + app.MicGainEditField.Value);
      end
   end
   
   methods (Access = public)
      
      function result = AcquireValue(app, f)
         if ~app.running
            result = NaN;
            return;
         end
         app.acqFreq = f;

         app.StopButton.Enable = false;

         app.numAcquired = 0;
         app.acquire = true;

         while app.acquire
            pause(0.25);
         end

         result = app.acquiredValue;
         app.StopButton.Enable = true;
      end
      
      %--------------------------------------------------------------------
      function Quit(app)
         close(app.UIFigure);
      end
   end
   

   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app)
         app.ResolutionHzEditField.Value = getsharedpref('SoundMeter', 'Resolution_Hz', 1);
         app.PistonphoneEditField.Value = getsharedpref('SoundMeter', 'Pistonphone_dB', 94);
         app.MicGainEditField.Value = getsharedpref('SoundMeter', 'Gain', 30);
         app.MicSensitivityEditField.Value = getsharedpref('SoundMeter', 'Sensitivity', -133);

         % Restore figure position
         pos = app.UIFigure.Position;
         origin = getsharedpref('SoundMeter', 'Position', []);
         screenSize = get(0, 'ScreenSize');
         if ~isempty(origin) && origin(1) < screenSize(3) 
            pos(1:2) = origin;
            app.UIFigure.Position = pos;
         end
      end

      % Close request function: UIFigure
      function UIFigureCloseRequest(app, event)
         if app.running
            stop(app.daq_handle);
         end
         
         pos = app.UIFigure.Position;
         setsharedpref('SoundMeter', 'Position', pos(1:2));
         delete(app)         
      end

      % Button pushed function: RunButton
      function RunButtonPushed(app, event)
         app.RunButton.Enable = false;
         app.StartAcquisition();
      end

      % Button pushed function: StopButton
      function StopButtonPushed(app, event)
         app.StopButton.Enable = false;
         stop(app.daq_handle);
         app.RunButton.Enable = true;
         app.running = false;
      end

      % Button pushed function: MeasureButton
      function MeasureButtonPushed(app, event)
         if app.running
            app.MeasureButton.Enable = false;
            value = app.AcquireValue() - app.correctionFactor;
            value = value - app.MicGainEditField.value;
            value = value - app.PistonphoneEditField.Value;
            
            app.MicSensitivityEditField.value = value;
            app.UpdateCorrectionFactor();

            setsharedpref('SoundMeter', 'Sensitivity', value);

            app.MeasureButton.Enable = true;
         end
      end

      % Value changed function: ResolutionHzEditField
      function ResolutionHzEditFieldValueChanged(app, event)
         setsharedpref('SoundMeter', 'Resolution_Hz', app.ResolutionHzEditField.Value);
      end

      % Value changed function: PistonphoneEditField
      function PistonphoneEditFieldValueChanged(app, event)
         setsharedpref('SoundMeter', 'Pistonphone_dB', app.PistonphoneEditField.Value);         
      end

      % Value changed function: MicGainEditField
      function MicGainEditFieldValueChanged(app, event)
         setsharedpref('SoundMeter', 'Gain', app.MicGainEditField.Value);
         app.UpdateCorrectionFactor();
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.Name = 'Sound Meter';
         app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
         app.FreqAxes = uiaxes(app.UIFigure);
         xlabel(app.FreqAxes, 'Frequency (Hz)')
         ylabel(app.FreqAxes, 'dB SPL')
         zlabel(app.FreqAxes, 'Z')
         app.TimeAxes = uiaxes(app.UIFigure);
         xlabel(app.TimeAxes, 'Time (ms)')
         ylabel(app.TimeAxes, 'Amplitude')
         zlabel(app.TimeAxes, 'Z')
         app.RunButton = uibutton(app.UIFigure, 'push');
         app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
         app.RunButton.Text = 'Run';
         app.ResolutionHzEditFieldLabel = uilabel(app.UIFigure);
         app.ResolutionHzEditFieldLabel.Text = 'Resolution (Hz)';
         app.ResolutionHzEditField = uieditfield(app.UIFigure, 'numeric');
         app.ResolutionHzEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionHzEditFieldValueChanged, true);
         app.PistonphonedBSPLEditFieldLabel = uilabel(app.UIFigure);
         app.PistonphonedBSPLEditFieldLabel.Text = 'Pistonphone (dB SPL)';
         app.PistonphoneEditField = uieditfield(app.UIFigure, 'numeric');
         app.PistonphoneEditField.ValueChangedFcn = createCallbackFcn(app, @PistonphoneEditFieldValueChanged, true);
         app.MeasureButton = uibutton(app.UIFigure, 'push');
         app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureButtonPushed, true);
         app.MeasureButton.Text = 'Measure';
         app.MicsensitivitydBVSPLLabel = uilabel(app.UIFigure);
         app.MicsensitivitydBVSPLLabel.Text = 'Mic sensitivity (dB V/SPL)';
         app.MicSensitivityEditField = uieditfield(app.UIFigure, 'numeric');
         app.MicSensitivityEditField.Editable = 'off';
         app.StopButton = uibutton(app.UIFigure, 'push');
         app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
         app.StopButton.Enable = 'off';
         app.StopButton.Text = {'Stop'; ''};
         app.SPLField = uieditfield(app.UIFigure, 'numeric');
         app.SPLField.ValueDisplayFormat = '%11.1f';
         app.dBSPLLabel = uilabel(app.UIFigure);
         app.dBSPLLabel.Text = 'dB SPL';
         app.VrmsField = uieditfield(app.UIFigure, 'numeric');
         app.VrmsField.ValueDisplayFormat = '%11.2f';
         app.dBSPLLabel_2 = uilabel(app.UIFigure);
         app.dBSPLLabel_2.Text = 'dB Vrms';
         app.MicgaindBLabel = uilabel(app.UIFigure);
         app.MicgaindBLabel.Text = 'Mic gain (dB)';
         app.MicGainEditField = uieditfield(app.UIFigure, 'numeric');
         app.MicGainEditField.ValueChangedFcn = createCallbackFcn(app, @MicGainEditFieldValueChanged, true);
         app.MicGainEditField.Editable = 'off';
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = Sound_Meter

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.UIFigure)

         % Execute the startup function
         runStartupFcn(app, @startupFcn)

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end